classdef robot_marge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CreateRobotTab                matlab.ui.container.Tab
        Joint3Slider_2Label           matlab.ui.control.Label
        CreatetherobotandmoveitusingtheslidersLabel  matlab.ui.control.Label
        Joint3Slider                  matlab.ui.control.Slider
        Joint2Slider                  matlab.ui.control.Slider
        Joint2SliderLabel             matlab.ui.control.Label
        Joint1Slider                  matlab.ui.control.Slider
        Joint1SliderLabel             matlab.ui.control.Label
        CreateRobotButton             matlab.ui.control.Button
        KinematicsTab                 matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        ForwardKinematicsTab          matlab.ui.container.Tab
        Joint3DistanceSpinner         matlab.ui.control.Spinner
        Joint3DistanceSpinnerLabel    matlab.ui.control.Label
        Joint2AngleSpinner            matlab.ui.control.Spinner
        Joint2AngleSpinnerLabel       matlab.ui.control.Label
        Joint1AngleSpinner            matlab.ui.control.Spinner
        Joint1AngleSpinnerLabel       matlab.ui.control.Label
        zEditField                    matlab.ui.control.NumericEditField
        zEditFieldLabel               matlab.ui.control.Label
        yEditField                    matlab.ui.control.NumericEditField
        yEditFieldLabel               matlab.ui.control.Label
        xEditField                    matlab.ui.control.NumericEditField
        xEditFieldLabel               matlab.ui.control.Label
        EnterthejointparametersLabel  matlab.ui.control.Label
        ForwardKinematicsButton       matlab.ui.control.Button
        InverseKinematicsTab          matlab.ui.container.Tab
        ChooseIKModeDropDown          matlab.ui.control.DropDown
        ChooseIKModeDropDownLabel     matlab.ui.control.Label
        UsebuttonstomovetheendeffectorpositionLabel  matlab.ui.control.Label
        DownButton                    matlab.ui.control.Button
        UpButton                      matlab.ui.control.Button
        LeftButton                    matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        RobotMargeLabel               matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        robot
        joint_params
        target_position
    end
    
    methods (Access = private)
        
        % Startup function for initializing joint parameters.
        function startupFcn(app)
            app.joint_params = [0 0 0];
        end

        % Helper function for computing closed-form IK.
        function q = closedFormIK(app, x, y)
            % Link lengths.
            L1 = app.robot.links(1).a;
            L2 = app.robot.links(2).a;

            % Formulas below are from the in-class IK slides.
            cos2 = (x^2 + y^2 - L1^2 - L2^2) / (2 * L1 * L2);

            % Avoid errors.
            cos2 = max(min(cos2, 1), -1);

            q2 = acos(cos2);
            q1 = atan2(y, x) - atan2(L2*sin(q2), L1 + L2*cos(q2));
            d3 = app.joint_params(3);

            q = [q1 q2 d3];
        end


        % Helper function for plotting 2D model of robot.
        function robotModel2D(app)
            if isempty(app.robot)
                uialert(app.UIFigure, 'Create the robot first in the other tab.', 'No Robot');
                return
            end

            % Joint angles.
            q1 = app.joint_params(1);
            q2 = app.joint_params(2);

            % Link lengths.
            L1 = app.robot.links(1).a;
            L2 = app.robot.links(2).a;

            % Base point.
            x0 = 0;
            y0 = 0;

            % Joint 1 position.
            x1 = L1 * cos(q1);
            y1 = L1 * sin(q1);

            % Joint 2 position (end-effector).
            x2 = x1 + L2*cos(q1 + q2);
            y2 = y1 + L2*sin(q1 + q2);

            % Plot links and joints.
            cla(app.UIAxes);
            hold(app.UIAxes, 'on'); 
            plot(app.UIAxes, [x0 x1 x2], [y0 y1 y2], '-o')
            xlim(app.UIAxes, [-20, 20]);
            ylim(app.UIAxes, [-20, 20]);
            axis(app.UIAxes, 'equal');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
        end

        % Helper function for updating robot models when slider values change.
        function updateRobotPlot(app)
            if isempty(app.robot)
                uialert(app.UIFigure, 'Create the robot first.', 'No Robot');
                return
            end

            % Read the sliders.
            q1 = deg2rad(app.Joint1Slider.Value);
            q2 = deg2rad(app.Joint2Slider.Value);
            d3 = app.Joint3Slider.Value;

            % Update the joint parameters.
            app.joint_params = [q1 q2 d3];

            % Plot the 3D model of robot at the new position/orientation.
            app.robot.plot(app.joint_params)

            % Plot the 2D model of robot.
            robotModel2D(app);
        end


        % Helper function for IK and moving the robot.
        function moveToTarget(app,ClosedForm)
            if isempty(app.robot)
                uialert(app.UIFigure, 'Create the robot first in the other tab.', 'No Robot');
                return
            end

            x = app.target_position(1);
            y = app.target_position(2);
            z = app.target_position(3);

            % Perform closed form or numerical IK.
            if ClosedForm
                q = closedFormIK(app, x, y);
            else
                % Only need position.
                T = transl(x, y, z);
                q = app.robot.ikine(T, app.joint_params, [1 1 1 0 0 0]); 
            end

            % Convert joint parameters to degrees.
            q1_deg = rad2deg(q(1));
            q2_deg = rad2deg(q(2));
            d3 = q(3);

            % Ensure that joints stay within limits.
            if q1_deg < 0
                q1_deg = 0;
            end

            if q2_deg > 155
                q2_deg = 155;
            end

            if d3 < 3
                d3 = 3;
            end

            if d3 > 9
                d3 = 9;
            end

            % Update joint parameters.
            app.joint_params = [deg2rad(q1_deg) deg2rad(q2_deg) d3];

            % Update sliders with degree values.
            app.Joint1Slider.Value = q1_deg;
            app.Joint2Slider.Value = q2_deg;
            app.Joint3Slider.Value = d3;

            % Replot 3D and 2D models of robot.
            app.robot.plot(app.joint_params);
            robotModel2D(app);

            % Update XYZ fields in GUI.
            T_new = app.robot.fkine(app.joint_params).T;

            app.xEditField.Value = T_new(1,4);
            app.yEditField.Value = T_new(2,4);
            app.zEditField.Value = T_new(3,4);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateRobotButton
        function CreateRobotButtonPushed(app, event)
            % Define links with DH parameters.
            L(1) = Link('revolute', 'd', 3.2, 'a', 10.5, 'alpha', 0, 'offset', deg2rad(8), 'qlim', deg2rad([0 185]));
            L(2) = Link('revolute', 'd', 0.5, 'a', 10.3, 'alpha', pi, 'offset', deg2rad(-25), 'qlim', deg2rad([0 155]));
            L(3) = Link('prismatic', 'theta', 0, 'a', 0, 'alpha', 0, 'offset', -3, 'qlim', [3 9]);

            % Create robot.
            app.robot = SerialLink(L, 'name', 'Robot Marge');

            % Reset joint parameters and sliders.
            app.joint_params = [0 0 0];
            app.Joint1Slider.Value = 0;
            app.Joint2Slider.Value = 0;
            app.Joint3Slider.Value = 0;

            % Plot robot.
            updateRobotPlot(app);

            % Set target position to current end-effector.
            T = app.robot.fkine(app.joint_params).T;
            app.target_position = T(1:3,4).'; 
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            updateRobotPlot(app);
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            updateRobotPlot(app);
        end

        % Callback function: not associated with a component
        function Joint3SliderValueChanged(app, event)
            updateRobotPlot(app);
        end

        % Button pushed function: ForwardKinematicsButton
        function ForwardKinematicsButtonPushed(app, event)
            if isempty(app.robot)
                uialert(app.UIFigure, 'Create the robot first in the other tab.', 'No Robot');
                return
            end

            % Read joint values from edit fields.
            q1 = deg2rad(app.Joint1AngleSpinner.Value);
            q2 = deg2rad(app.Joint2AngleSpinner.Value);
            d3 = app.Joint3DistanceSpinner.Value;

            q = [q1 q2 d3];

            % Compute forward kinematics, extract 4x4.
            T = app.robot.fkine(q).T;

            % Extract XYZ from T.
            x = T(1,4);
            y = T(2,4);
            z = T(3,4);

            % Show values in GUI.
            app.xEditField.Value = x;
            app.yEditField.Value = y;
            app.zEditField.Value = z;

            app.joint_params = q;

            updateRobotPlot(app);
            robotModel2D(app);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            step = 0.5;
            app.target_position(1) = app.target_position(1) + step;
            
            closed_form = strcmp(app.ChooseIKModeDropDown.Value, 'Closed-Form IK');
            moveToTarget(app, closed_form);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            step = 0.5;
            app.target_position(1) = app.target_position(1) - step;
            
            closed_form = strcmp(app.ChooseIKModeDropDown.Value, 'Closed-Form IK');
            moveToTarget(app, closed_form);
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            step = 0.5;
            app.target_position(2) = app.target_position(2) + step;
            
            closed_form = strcmp(app.ChooseIKModeDropDown.Value, 'Closed-Form IK');
            moveToTarget(app, closed_form);
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            step = 0.5;
            app.target_position(2) = app.target_position(2) - step;
            
            closed_form = strcmp(app.ChooseIKModeDropDown.Value, 'Closed-Form IK');
            moveToTarget(app, closed_form);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot Marge')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RobotMargeLabel = uilabel(app.UIFigure);
            app.RobotMargeLabel.Text = 'Robot Marge';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CreateRobotTab = uitab(app.TabGroup);
            app.CreateRobotTab.Title = 'Create Robot';
            app.CreateRobotButton = uibutton(app.CreateRobotTab, 'push');
            app.CreateRobotButton.ButtonPushedFcn = createCallbackFcn(app, @CreateRobotButtonPushed, true);
            app.CreateRobotButton.Text = 'Create Robot';
            app.Joint1SliderLabel = uilabel(app.CreateRobotTab);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.CreateRobotTab);
            app.Joint1Slider.Limits = [0 185];
            app.Joint1Slider.MajorTicks = [0 92.5 185];
            app.Joint1Slider.MajorTickLabels = {'0', '92.5', '185'};
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.CreateRobotTab);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.CreateRobotTab);
            app.Joint2Slider.Limits = [0 155];
            app.Joint2Slider.MajorTicks = [0 77.5 155];
            app.Joint2Slider.MajorTickLabels = {'0', '77.5', '155'};
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint3Slider = uislider(app.CreateRobotTab);
            app.Joint3Slider.Limits = [0 15];
            app.Joint3Slider.MajorTicks = [0 7.5 15];
            app.Joint3Slider.MajorTickLabels = {'0', '7.5', '15'};
            app.CreatetherobotandmoveitusingtheslidersLabel = uilabel(app.CreateRobotTab);
            app.CreatetherobotandmoveitusingtheslidersLabel.Text = {'Create the robot and move it '; 'using the sliders:'};
            app.Joint3Slider_2Label = uilabel(app.CreateRobotTab);
            app.Joint3Slider_2Label.Text = 'Joint 3';
            app.KinematicsTab = uitab(app.TabGroup);
            app.KinematicsTab.Title = 'Kinematics';
            app.TabGroup2 = uitabgroup(app.KinematicsTab);
            app.ForwardKinematicsTab = uitab(app.TabGroup2);
            app.ForwardKinematicsTab.Title = 'Forward Kinematics';
            app.ForwardKinematicsButton = uibutton(app.ForwardKinematicsTab, 'push');
            app.ForwardKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardKinematicsButtonPushed, true);
            app.ForwardKinematicsButton.Text = 'Forward Kinematics';
            app.EnterthejointparametersLabel = uilabel(app.ForwardKinematicsTab);
            app.EnterthejointparametersLabel.Text = 'Enter the joint parameters:';
            app.xEditFieldLabel = uilabel(app.ForwardKinematicsTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.yEditFieldLabel = uilabel(app.ForwardKinematicsTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.zEditFieldLabel = uilabel(app.ForwardKinematicsTab);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.Joint1AngleSpinnerLabel = uilabel(app.ForwardKinematicsTab);
            app.Joint1AngleSpinnerLabel.Text = 'Joint 1 (Angle)';
            app.Joint1AngleSpinner = uispinner(app.ForwardKinematicsTab);
            app.Joint2AngleSpinnerLabel = uilabel(app.ForwardKinematicsTab);
            app.Joint2AngleSpinnerLabel.Text = 'Joint 2 (Angle)';
            app.Joint2AngleSpinner = uispinner(app.ForwardKinematicsTab);
            app.Joint3DistanceSpinnerLabel = uilabel(app.ForwardKinematicsTab);
            app.Joint3DistanceSpinnerLabel.Text = 'Joint 3 (Distance)';
            app.Joint3DistanceSpinner = uispinner(app.ForwardKinematicsTab);
            app.InverseKinematicsTab = uitab(app.TabGroup2);
            app.InverseKinematicsTab.Title = 'Inverse Kinematics';
            app.RightButton = uibutton(app.InverseKinematicsTab, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.LeftButton = uibutton(app.InverseKinematicsTab, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.UpButton = uibutton(app.InverseKinematicsTab, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.InverseKinematicsTab, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.UsebuttonstomovetheendeffectorpositionLabel = uilabel(app.InverseKinematicsTab);
            app.UsebuttonstomovetheendeffectorpositionLabel.Text = {'Use buttons to move the '; 'end-effector position:'};
            app.ChooseIKModeDropDownLabel = uilabel(app.InverseKinematicsTab);
            app.ChooseIKModeDropDownLabel.Text = 'Choose IK Mode';
            app.ChooseIKModeDropDown = uidropdown(app.InverseKinematicsTab);
            app.ChooseIKModeDropDown.Items = {'Closed-Form IK', 'Numerical IK'};
            app.ChooseIKModeDropDown.Value = 'Closed-Form IK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_marge

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end